classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WestCambridgeDeliveryMapPanel  matlab.ui.container.Panel
        CustomOrderPanel               matlab.ui.container.Panel
        PickupLat                      matlab.ui.control.NumericEditField
        PickupLong                     matlab.ui.control.NumericEditField
        LatitudeLabel                  matlab.ui.control.Label
        LongitudeLabel                 matlab.ui.control.Label
        PickupLocationLabel            matlab.ui.control.Label
        PickupPresets                  matlab.ui.control.ListBox
        UploadMissionButton            matlab.ui.control.Button
        DeliveryLat                    matlab.ui.control.NumericEditField
        DeliveryLong                   matlab.ui.control.NumericEditField
        LatitudeLabel_2                matlab.ui.control.Label
        LongitudeLabel_2               matlab.ui.control.Label
        DeliveryLocationLabel          matlab.ui.control.Label
        DeliveryPresets                matlab.ui.control.ListBox
        DroneLat                       matlab.ui.control.NumericEditField
        DroneLong                      matlab.ui.control.NumericEditField
        LatitudeLabel_3                matlab.ui.control.Label
        LongitudeLabel_3               matlab.ui.control.Label
        DroneLocationLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        gx;
        droneMarker;
        droneRoute;
        
        gcObj; % Description
        
        coord_map = containers.Map();
        nodes = [];
        distance_cache = zeros(300, 300);
        gcDistance = zeros(1, 300);
        mission_queue = zeros(300, 2);
        left = 1;
        right = 1;
    end
    
    properties (Access = public)
    end
    
    methods (Access = public)
        %         function updateGUI(app, varargin)
        %             % create objects to get runtime values of blocks in Simulink
        %             rto0 = get_param([bdroot,'/Guidance Logic/Full Guidance Logic/dispX'],'RuntimeObject');
        %             rto1 = get_param([bdroot,'/Guidance Logic/Full Guidance Logic/dispY'],'RuntimeObject');
        %
        %             % update GUI
        %             app.LatitudeEditField.Value = string(rto0.InputPort(1).Data);
        %             app.LongitudeEditField.Value = string(rto1.InputPort(1).Data);
        %
        %             CamCenter = [52.2116,0.0869];
        %             tempOffset = -[-186,-105];
        %             dronePos = CamCenter + (tempOffset + [rto0.InputPort(1).Data,rto1.InputPort(1).Data])/(40*10^6) * 1000;
        %             app.gx.MapCenter = dronePos;
        %             app.droneMarker.LatitudeData = dronePos(1);
        %             app.droneMarker.LongitudeData = dronePos(2);
        %             hold(app.gx,"on");
        %         end
        
        function updateGUI_DronePosition(app, dronePos)
            app.droneMarker.LatitudeData = dronePos(1);
            app.droneMarker.LongitudeData = dronePos(2);
            hold(app.gx,"on");
        end
        
        function refreshRoute(app)
            routes = [];
            for idx = 1:2:size(app.mission_queue)
                loc1 = app.mission_queue(idx,:);
                loc2 = app.mission_queue(idx+1,:);
                waypoints = computeRRTStarRoute(loc1,loc2,true,true);
                if waypoints<0
                    disp(idx);
                    disp(waypoints);
                    disp(loc1);
                    disp(loc2);
                else
                    routes = cat(1,routes,waypoints);
                end
            end
            if ~isempty(routes)
                app.droneRoute.LatitudeData = routes(:,1);
                app.droneRoute.LongitudeData = routes(:,2);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            CamCenter = [52.2116,0.0869];
            app.gx = geoaxes(app.WestCambridgeDeliveryMapPanel ,"Basemap","streets","MapCenter",CamCenter,"ZoomLevel",15);
            hold(app.gx,"on");
            app.droneMarker = geoplot(app.gx,CamCenter(1),CamCenter(2),'or');
            hold(app.gx,"on");
            WGates = [52.21131142512676, 0.09120338879205804];
            Maxwell = [52.209930406566144, 0.0914800902281618];
            app.droneRoute = geoplot(app.gx,[0],[0],"LineWidth",2);
            hold(app.gx,"on");
            
            app.PickupPresets.Items = {'Computer Lab'};
            app.PickupPresets.ItemsData = [10];
            
            % temp line plot test below
            % geoplot(app.gx,[WGates(1),Cavendish(1)],[WGates(2),Cavendish(2)],"LineWidth",2);
            
            %             app.gx.Interactions = [];
            %             disableDefaultInteractivity(app.gx);
            
            %             app.locations = containers.Map(["Computer Lab"],[[1 1]]);
            
            app.gcObj = GroundControl();
            %             disp(size(app.mission_queue))
            %             app.mission_queue = [WGates;Maxwell];
            %             disp(size(app.mission_queue))
            
            app.mission_queue = [];
            newQ = scheduler_wrapper([WGates;Maxwell],app.coord_map,app.nodes,app.distance_cache,app.gcDistance,app.mission_queue,app.left,app.right);
            disp(newQ);
            disp(app.mission_queue);
            app.mission_queue = newQ;
            refreshRoute(app);
        end

        % Button pushed function: UploadMissionButton
        function UploadMissionButtonPushed(app, event)
            app.UploadMissionButton.Enable = "off";
            newMission = [[app.PickupLat.Value app.PickupLong.Value];[app.DeliveryLat.Value app.DeliveryLong.Value]];
            disp(newMission);
            %             assignin("base", "hApp", app.newMission);
            pause(1);
            app.UploadMissionButton.Enable = "on";
            newQ = scheduler_wrapper(newMission,app.coord_map,app.distance_cache,app.gcDistance,app.mission_queue,app.left,app.right);
            app.mission_queue = newQ;
            disp(app.mission_queue);
            refreshRoute(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disp(app.gcObj.getPosition());
            app.gcObj.delete();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.WestCambridgeDeliveryMapPanel = uipanel(app.UIFigure);
            app.WestCambridgeDeliveryMapPanel.Title = 'West Cambridge Delivery Map';
            app.CustomOrderPanel = uipanel(app.UIFigure);
            app.CustomOrderPanel.Title = 'Custom Order';
            app.PickupLat = uieditfield(app.CustomOrderPanel, 'numeric');
            app.PickupLong = uieditfield(app.CustomOrderPanel, 'numeric');
            app.LatitudeLabel = uilabel(app.CustomOrderPanel);
            app.LatitudeLabel.Text = 'Latitude';
            app.LongitudeLabel = uilabel(app.CustomOrderPanel);
            app.LongitudeLabel.Text = 'Longitude';
            app.PickupLocationLabel = uilabel(app.CustomOrderPanel);
            app.PickupLocationLabel.Text = 'Pickup Location';
            app.PickupPresets = uilistbox(app.CustomOrderPanel);
            app.UploadMissionButton = uibutton(app.CustomOrderPanel, 'push');
            app.UploadMissionButton.ButtonPushedFcn = createCallbackFcn(app, @UploadMissionButtonPushed, true);
            app.UploadMissionButton.Text = {'Upload'; 'Mission'};
            app.DeliveryLat = uieditfield(app.CustomOrderPanel, 'numeric');
            app.DeliveryLong = uieditfield(app.CustomOrderPanel, 'numeric');
            app.LatitudeLabel_2 = uilabel(app.CustomOrderPanel);
            app.LatitudeLabel_2.Text = 'Latitude';
            app.LongitudeLabel_2 = uilabel(app.CustomOrderPanel);
            app.LongitudeLabel_2.Text = 'Longitude';
            app.DeliveryLocationLabel = uilabel(app.CustomOrderPanel);
            app.DeliveryLocationLabel.Text = 'Delivery Location';
            app.DeliveryPresets = uilistbox(app.CustomOrderPanel);
            app.DroneLat = uieditfield(app.UIFigure, 'numeric');
            app.DroneLong = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeLabel_3 = uilabel(app.UIFigure);
            app.LatitudeLabel_3.Text = 'Latitude';
            app.LongitudeLabel_3 = uilabel(app.UIFigure);
            app.LongitudeLabel_3.Text = 'Longitude';
            app.DroneLocationLabel = uilabel(app.UIFigure);
            app.DroneLocationLabel.Text = 'Drone Location';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end